classdef GetExtensometerPointApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        CenterPanel                     matlab.ui.container.Panel
        Y_cEditFieldLabel               matlab.ui.control.Label
        Y_cEditField                    matlab.ui.control.NumericEditField
        X_cEditFieldLabel               matlab.ui.control.Label
        X_cEditField                    matlab.ui.control.NumericEditField
        PlotcurrentconfigurationButton  matlab.ui.control.Button
        PointsdistancemodulePanel       matlab.ui.container.Panel
        X_1EditFieldLabel               matlab.ui.control.Label
        X_1EditField                    matlab.ui.control.NumericEditField
        X_2EditFieldLabel               matlab.ui.control.Label
        X_2EditField                    matlab.ui.control.NumericEditField
        Y_1EditFieldLabel               matlab.ui.control.Label
        Y_1EditField                    matlab.ui.control.NumericEditField
        Y_2EditFieldLabel               matlab.ui.control.Label
        Y_2EditField                    matlab.ui.control.NumericEditField
        ConfirmButton                   matlab.ui.control.Button
        Panel                           matlab.ui.container.Panel
        SelectthepositionoftheextensometerLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        refimage % Reference image
        mask  % ROI mask
        info_image
        coefficent
        units
        fig 
        axis_plot
        image_plot
        center_image
        pixel_spacing
        selected_point
        actual_plot
        line_plot
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, reference_image, mask, coeff_pixel, units, spacing)
            image = imread(reference_image);
            
            app.UIFigure.Color = [0.96 0.96 0.96];
            app.mask = mask;
            app.info_image = imfinfo(reference_image);
            app.coefficent = coeff_pixel;
            app.units = units;
            app.pixel_spacing = spacing;
            app.fig = figure;
            app.fig.OuterPosition(1) = app.UIFigure.OuterPosition(1)+app.UIFigure.OuterPosition(3);
            app.fig.OuterPosition(2) = app.UIFigure.OuterPosition(2);
            app.fig.OuterPosition(4) = app.UIFigure.OuterPosition(4)*1.5;
            app.fig.Color = 'white';
            app.axis_plot = axes(app.fig);
            app.selected_point = zeros(3,2);
            
            
            [M,N] = size(image);
            for i = 1:M
                for j = 1:N
                    if mask(i,j)
                    else
                        image(i,j) = 255;
                    end
                end
            end
            
            app.center_image(1) = round(N/2);    % X in nodi del centro
            app.center_image(2) = round(M/2);    % Y in nodi del centro

            image_width = app.info_image.Width;
            image_height = app.info_image.Height;
            
            x_center_px = round(image_width/2);
            y_center_px = round(image_height/2);
            
            x_axes = (1:image_width)';          x_axes = x_axes - x_center_px;
            y_axes = (1:image_height)';         y_axes = y_axes - y_center_px;
            
            app.image_plot = imshow(image,'Xdata',x_axes,'Ydata',y_axes,'Parent',app.axis_plot);
            app.axis_plot.Visible = 'On';
            hold on;
            % Inizializzo affinché sia trattato come un plot e non un valore numerico
            app.actual_plot = scatter(app.axis_plot,0,0,'k');
            app.line_plot = plot(app.axis_plot,[0 0],[0 0],'k');


            % Impedire allo script principale di andare avanti prima che la selezione dei punti sia
            % conclusa e la finestra di selezione si chiuda.
            uiwait(app.UIFigure)

        end

        % Button pushed function: PlotcurrentconfigurationButton
        function PlotcurrentconfigurationButtonPushed(app, event)
            X_c = app.X_cEditField.Value;
            Y_c = app.Y_cEditField.Value;
            
            xc_node = round(X_c / (app.pixel_spacing + 1));           
            yc_node = round(Y_c / (app.pixel_spacing + 1));
            x1_node = round(app.X_1EditField.Value / (app.pixel_spacing + 1));
            x2_node = round(app.X_2EditField.Value / (app.pixel_spacing + 1));
            y1_node = round(app.Y_1EditField.Value / (app.pixel_spacing + 1));
            y2_node = round(app.Y_2EditField.Value / (app.pixel_spacing + 1));
            
            xcp = xc_node * app.pixel_spacing;           app.selected_point(1,1) = xc_node;
            ycp = yc_node * app.pixel_spacing;           app.selected_point(1,2) = yc_node;
            x1p = xcp - x1_node * app.pixel_spacing;     app.selected_point(2,1) = x1_node;
            x2p = xcp + x2_node * app.pixel_spacing;     app.selected_point(2,2) = x2_node;
            y1p = ycp - y1_node * app.pixel_spacing;     app.selected_point(3,1) = y1_node;
            y2p = ycp + y2_node * app.pixel_spacing;     app.selected_point(3,2) = y2_node;
            
            if isvalid(app.actual_plot)
                delete(app.actual_plot)
                delete(app.line_plot)
            end
            
            app.actual_plot = scatter(app.axis_plot,[xcp x1p x2p xcp xcp],[ycp ycp ycp y1p y2p],'g','filled','o');
            app.line_plot = plot(app.axis_plot,[x1p x2p],[ycp ycp],'g',[xcp xcp],[y1p y2p],'g');
        end

        % Button pushed function: ConfirmButton
        function ConfirmButtonPushed(app, event)
            PlotcurrentconfigurationButtonPushed(app, event)
            
            assignin('base','extensometer_point',app.selected_point);
            
            close(app.fig);
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.CenterPanel = uipanel(app.UIFigure);
            app.CenterPanel.Title = 'Center';
            app.Y_cEditFieldLabel = uilabel(app.CenterPanel);
            app.Y_cEditFieldLabel.Text = 'Y_c';
            app.Y_cEditField = uieditfield(app.CenterPanel, 'numeric');
            app.X_cEditFieldLabel = uilabel(app.CenterPanel);
            app.X_cEditFieldLabel.Text = 'X_c';
            app.X_cEditField = uieditfield(app.CenterPanel, 'numeric');
            app.PlotcurrentconfigurationButton = uibutton(app.UIFigure, 'push');
            app.PlotcurrentconfigurationButton.ButtonPushedFcn = createCallbackFcn(app, @PlotcurrentconfigurationButtonPushed, true);
            app.PlotcurrentconfigurationButton.Text = 'Plot current configuration';
            app.PointsdistancemodulePanel = uipanel(app.UIFigure);
            app.PointsdistancemodulePanel.Title = 'Points distance (module)';
            app.X_1EditFieldLabel = uilabel(app.PointsdistancemodulePanel);
            app.X_1EditFieldLabel.Text = 'X_1';
            app.X_1EditField = uieditfield(app.PointsdistancemodulePanel, 'numeric');
            app.X_2EditFieldLabel = uilabel(app.PointsdistancemodulePanel);
            app.X_2EditFieldLabel.Text = 'X_2';
            app.X_2EditField = uieditfield(app.PointsdistancemodulePanel, 'numeric');
            app.Y_1EditFieldLabel = uilabel(app.PointsdistancemodulePanel);
            app.Y_1EditFieldLabel.Text = 'Y_1';
            app.Y_1EditField = uieditfield(app.PointsdistancemodulePanel, 'numeric');
            app.Y_2EditFieldLabel = uilabel(app.PointsdistancemodulePanel);
            app.Y_2EditFieldLabel.Text = 'Y_2';
            app.Y_2EditField = uieditfield(app.PointsdistancemodulePanel, 'numeric');
            app.ConfirmButton = uibutton(app.UIFigure, 'push');
            app.ConfirmButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmButtonPushed, true);
            app.ConfirmButton.Text = 'Confirm';
            app.Panel = uipanel(app.UIFigure);
            app.SelectthepositionoftheextensometerLabel = uilabel(app.Panel);
            app.SelectthepositionoftheextensometerLabel.Text = {'Select the position '; 'of the extensometer'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GetExtensometerPointApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end