classdef GetMultipleSectionPoint3D < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        NodesmoothingSliderLabel   matlab.ui.control.Label
        NodesmoothingSlider        matlab.ui.control.Slider
        SectioncoordinatesPanel    matlab.ui.container.Panel
        ZsectionEditFieldLabel     matlab.ui.control.Label
        ZsectionEditField          matlab.ui.control.NumericEditField
        ToleranceLabel             matlab.ui.control.Label
        ToleranceEditField         matlab.ui.control.NumericEditField
        PlotsectionButton          matlab.ui.control.Button
        ButtonGroup                matlab.ui.container.ButtonGroup
        SectionnEditFieldLabel     matlab.ui.control.Label
        SectionnEditField          matlab.ui.control.NumericEditField
        AddcurrentsectionButton    matlab.ui.control.Button
        FinishButton               matlab.ui.control.Button
        NofpointsLabel             matlab.ui.control.Label
        Label                      matlab.ui.control.Label
        FrameSliderLabel           matlab.ui.control.Label
        FrameSlider                matlab.ui.control.Slider
        ClearcurrentsectionButton  matlab.ui.control.Button
        TypeofanalysisPanel        matlab.ui.container.Panel
        AxialdeformationCheckBox   matlab.ui.control.CheckBox
        RotationCheckBox           matlab.ui.control.CheckBox
    end

    
    properties (Access = private)
        points3D
        plot_cloud
        fig
        axis_plot
        actual_plot
        section_coordinates
        section
        section_index
        zeta
        tolerance
        section_plot
        selected_section_coord
        actual_nodes
        actual_zeta
        actual_tolerance
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, points3D)
            app.UIFigure.Color = [0.96 0.96 0.96];
            
            app.points3D = points3D;
            app.UIFigure.Color = [0.94 0.94 0.94];
            app.fig = figure;
            app.fig.Position(1) = app.UIFigure.OuterPosition(1)+app.UIFigure.OuterPosition(3);
            app.fig.Position(2) = app.UIFigure.OuterPosition(2);
            app.fig.Position(4) = app.UIFigure.OuterPosition(4);
            app.fig.Color = 'white';
            app.axis_plot = axes(app.fig);
            app.NodesmoothingSlider.Value = 10;
            app.section = [0];
            app.zeta = [0];
            app.tolerance = [0];
            app.section_index = struct;
            app.selected_section_coord = struct;
            app.selected_section_coord(1).coordinates = 0;
            app.section_plot = gobjects(1);
            delete(app.section_plot(1));
            
            nframes = size(app.points3D,3);
            app.FrameSlider.Limits = [1,nframes];
            
            % Inizializzo i plot
            app.actual_plot = scatter3(app.axis_plot,points3D(1,1),points3D(1,2),points3D(3,1),'Visible','off');
           
            % Plotto i punti filtrati all'inizio della function
            app.plot_cloud = plotTracker3D(app.axis_plot,app.points3D,10);
            
            % Impedire allo script principale di andare avanti prima che la selezione dei punti sia
            % conclusa e la finestra di selezione si chiuda.
            uiwait(app.UIFigure)

        end

        % Value changed function: FrameSlider, NodesmoothingSlider
        function NodesmoothingSliderValueChanged(app, event)
            value = app.NodesmoothingSlider.Value;
            value = round(value);
            
            frame = app.FrameSlider.Value;
            frame = round(frame);
            
            delete(app.plot_cloud);
            hold on;
            app.plot_cloud = plotTracker3D(app.axis_plot,app.points3D(:,:,frame),value);
            hold off;
            
            if isvalid(app.actual_plot)
                delete(app.actual_plot)
                xp = app.section_coordinates(:,1,frame);
                yp = app.section_coordinates(:,2,frame);
                zp = app.section_coordinates(:,3,frame);
                hold on
                app.actual_plot = scatter3(app.axis_plot,xp,yp,zp,'r','filled','o');
                hold off
            end
            
            for i = 1:length(app.section)
                if isvalid(app.section_plot(i))
                    delete(app.section_plot(i))
                    xp = app.selected_section_coord(i).coordinates(:,1,frame);
                    yp = app.selected_section_coord(i).coordinates(:,2,frame);
                    zp = app.selected_section_coord(i).coordinates(:,3,frame);
                    hold on
                    app.section_plot(i) = scatter3(app.axis_plot,xp,yp,zp,'k','filled','o');
                    hold off
                end
            end
        end

        % Value changed function: SectionnEditField
        function SectionnEditFieldValueChanged(app, event)
            value = app.SectionnEditField.Value;
            
            [a,b] = ismember(value,app.section);
            
            if a
                app.ZsectionEditField.Value = app.zeta(b);
                app.ToleranceEditField.Value = app.tolerance(b);
                
                PlotsectionButtonPushed(app,event)
            end
        end

        % Button pushed function: PlotsectionButton
        function PlotsectionButtonPushed(app, event)
            z = app.ZsectionEditField.Value;
            tol = app.ToleranceEditField.Value;
            
            frame = app.FrameSlider.Value;
            frame = round(frame);
            
            app.actual_nodes = findSection3D(app.points3D(:,:,frame),z,tol);
            app.section_coordinates = extractSectionNode(app.actual_nodes,app.points3D);
            app.actual_zeta = z;
            app.actual_tolerance = tol;
            
            N = size(app.actual_nodes,1);
            app.Label.Text = num2str(N);
            
            xp = app.section_coordinates(:,1,frame);
            yp = app.section_coordinates(:,2,frame);
            zp = app.section_coordinates(:,3,frame);
            
            if isvalid(app.actual_plot)
                delete(app.actual_plot)
            end
            
            hold on
            app.actual_plot = scatter3(app.axis_plot,xp,yp,zp,'r','filled','o');
            hold off
        end

        % Button pushed function: AddcurrentsectionButton
        function AddcurrentsectionButtonPushed(app, event)
            frame = app.FrameSlider.Value;
            frame = round(frame);
            
            sectionN = app.SectionnEditField.Value;
            
            xp = app.section_coordinates(:,1,frame);
            yp = app.section_coordinates(:,2,frame);
            zp = app.section_coordinates(:,3,frame);
            
            [a,b] = ismember(sectionN,app.section);
            
            if a
                app.section(b) = sectionN;
                app.zeta(b) = app.actual_zeta;
                app.tolerance(b) = app.actual_tolerance;
                app.section_index(b).index = app.actual_nodes;
                app.selected_section_coord(b).coordinates = app.section_coordinates;
                delete(app.section_plot(b))
                hold on
                app.section_plot(b) = scatter3(app.axis_plot,xp,yp,zp,'k','filled','o');
                hold off
            else
                app.section = [app.section; sectionN];
                app.zeta = [app.zeta; app.actual_zeta];
                app.tolerance = [app.tolerance; app.actual_tolerance];
                app.section_index(end+1).index = app.actual_nodes;
                app.selected_section_coord(end+1).coordinates = app.section_coordinates;
                hold on
                app.section_plot = [app.section_plot; scatter3(app.axis_plot,xp,yp,zp,'k','filled','o')];
                hold off
            end
            
            if isequal(app.section(1),0)
                app.section(1) = [];
                app.zeta(1) = [];
                app.tolerance(1) = [];
                app.section_plot(1) = [];
                app.selected_section_coord(1) = [];
                app.section_index(1) = [];
            end
   
        end

        % Button pushed function: ClearcurrentsectionButton
        function ClearcurrentsectionButtonPushed(app, event)
            sectionN = app.SectionnEditField.Value;
            
            [a,b] = ismember(sectionN,app.section);
            
            if a
                app.section(b) = [];
                app.zeta(b) = [];
                app.tolerance(b) = [];
                app.selected_section_coord(b) = [];
                app.section_index(b) = [];
                delete(app.section_plot(b))
                app.section_plot(b) = [];
            end
        end

        % Button pushed function: FinishButton
        function FinishButtonPushed(app, event)
            AddcurrentsectionButtonPushed(app, event)
            
            if app.AxialdeformationCheckBox.Value
                assignin('base','AxialAnalysis',true)
            else
                assignin('base','AxialAnalysis',false)
            end
            if app.RotationCheckBox.Value
                assignin('base','RotationAnalysis',true)
            else
                assignin('base','RotationAnalysis',false)
            end
            assignin('base','sectionID',app.section)
            assignin('base','section_index',app.section_index)
            assignin('base','Z_section',app.zeta)
            assignin('base','tolerance',app.tolerance)
            
            close(app.fig);
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.NodesmoothingSliderLabel = uilabel(app.UIFigure);
            app.NodesmoothingSliderLabel.Text = {'Node'; 'smoothing'};
            app.NodesmoothingSlider = uislider(app.UIFigure);
            app.NodesmoothingSlider.Limits = [1 10];
            app.NodesmoothingSlider.MajorTicks = [1 2 3 4 5 6 7 8 9 10];
            app.NodesmoothingSlider.ValueChangedFcn = createCallbackFcn(app, @NodesmoothingSliderValueChanged, true);
            app.NodesmoothingSlider.MinorTicks = [1 2 3 4 5 6 7 8 9 10];
            app.NodesmoothingSlider.Value = 1;
            app.SectioncoordinatesPanel = uipanel(app.UIFigure);
            app.SectioncoordinatesPanel.Title = 'Section coordinates';
            app.ZsectionEditFieldLabel = uilabel(app.SectioncoordinatesPanel);
            app.ZsectionEditFieldLabel.Text = {'Z section'; ''};
            app.ZsectionEditField = uieditfield(app.SectioncoordinatesPanel, 'numeric');
            app.ToleranceLabel = uilabel(app.SectioncoordinatesPanel);
            app.ToleranceLabel.Text = 'Tolerance';
            app.ToleranceEditField = uieditfield(app.SectioncoordinatesPanel, 'numeric');
            app.PlotsectionButton = uibutton(app.SectioncoordinatesPanel, 'push');
            app.PlotsectionButton.ButtonPushedFcn = createCallbackFcn(app, @PlotsectionButtonPushed, true);
            app.PlotsectionButton.Text = 'Plot section';
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.SectionnEditFieldLabel = uilabel(app.ButtonGroup);
            app.SectionnEditFieldLabel.Text = 'Section n.';
            app.SectionnEditField = uieditfield(app.ButtonGroup, 'numeric');
            app.SectionnEditField.ValueChangedFcn = createCallbackFcn(app, @SectionnEditFieldValueChanged, true);
            app.AddcurrentsectionButton = uibutton(app.UIFigure, 'push');
            app.AddcurrentsectionButton.ButtonPushedFcn = createCallbackFcn(app, @AddcurrentsectionButtonPushed, true);
            app.AddcurrentsectionButton.Text = 'Add current section';
            app.FinishButton = uibutton(app.UIFigure, 'push');
            app.FinishButton.ButtonPushedFcn = createCallbackFcn(app, @FinishButtonPushed, true);
            app.FinishButton.Text = 'Finish';
            app.NofpointsLabel = uilabel(app.UIFigure);
            app.NofpointsLabel.Text = 'N. of points';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.FrameSliderLabel = uilabel(app.UIFigure);
            app.FrameSliderLabel.Text = 'Frame';
            app.FrameSlider = uislider(app.UIFigure);
            app.FrameSlider.ValueChangedFcn = createCallbackFcn(app, @NodesmoothingSliderValueChanged, true);
            app.ClearcurrentsectionButton = uibutton(app.UIFigure, 'push');
            app.ClearcurrentsectionButton.ButtonPushedFcn = createCallbackFcn(app, @ClearcurrentsectionButtonPushed, true);
            app.ClearcurrentsectionButton.Text = 'Clear current section';
            app.TypeofanalysisPanel = uipanel(app.UIFigure);
            app.TypeofanalysisPanel.Title = 'Type of analysis';
            app.AxialdeformationCheckBox = uicheckbox(app.TypeofanalysisPanel);
            app.AxialdeformationCheckBox.Text = 'Axial deformation';
            app.RotationCheckBox = uicheckbox(app.TypeofanalysisPanel);
            app.RotationCheckBox.Text = 'Rotation';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GetMultipleSectionPoint3D(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end