classdef GetPointTracker3D < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        CoordinatesPanel          matlab.ui.container.Panel
        XEditFieldLabel           matlab.ui.control.Label
        XEditField                matlab.ui.control.NumericEditField
        YEditFieldLabel           matlab.ui.control.Label
        YEditField                matlab.ui.control.NumericEditField
        ZEditFieldLabel           matlab.ui.control.Label
        ZEditField                matlab.ui.control.NumericEditField
        FindnearestnodeButton     matlab.ui.control.Button
        PointnEditFieldLabel      matlab.ui.control.Label
        PointnEditField           matlab.ui.control.NumericEditField
        Panel_2                   matlab.ui.container.Panel
        DiscetizationerrorLabel   matlab.ui.control.Label
        Label_2                   matlab.ui.control.Label
        Label                     matlab.ui.control.Label
        NodeindexLabel            matlab.ui.control.Label
        NodesmoothingSliderLabel  matlab.ui.control.Label
        NodesmoothingSlider       matlab.ui.control.Slider
        AddselectedpointButton    matlab.ui.control.Button
        ConfirmButton_2           matlab.ui.control.Button
        ClearselectedpointButton  matlab.ui.control.Button
        SelectIDofthepointstotrackLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        points3D                 % Coordinate dei punti
        plot_cloud
        fig
        axis_plot
        plot_point
        selected_point
        actual_index
        actual_plot
        zeta
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, points3D)
            app.points3D = points3D;
            app.fig = figure;
            app.fig.Position(1) = app.UIFigure.OuterPosition(1)+app.UIFigure.OuterPosition(3);
            app.fig.Position(2) = app.UIFigure.OuterPosition(2);
            app.fig.Position(4) = app.UIFigure.OuterPosition(4);
            app.fig.Color = 'white';
            app.axis_plot = axes(app.fig);
            app.NodesmoothingSlider.Value = 10;
            app.selected_point = [0 0];
            app.PointnEditField.Value = 1;
            app.plot_point = gobjects(1);
            
            app.actual_plot = scatter3(app.axis_plot,points3D(1,1),points3D(1,2),points3D(3,1),'Visible','off');
            
            % Plotto i punti filtrati all'inizio della function
            app.plot_cloud = plotTracker3D(app.axis_plot,app.points3D,10);
            
            % Impedire allo script principale di andare avanti prima che la selezione dei punti sia
            % conclusa e la finestra di selezione si chiuda.
            uiwait(app.UIFigure)
        end

        % Value changed function: NodesmoothingSlider
        function NodesmoothingSliderValueChanged(app, event)
            value = app.NodesmoothingSlider.Value;
            value = round(value);
            
            delete(app.plot_cloud);
            hold on;
            app.plot_cloud = plotTracker3D(app.axis_plot,app.points3D,value);
            hold off;
        end

        % Value changed function: PointnEditField
        function PointnEditFieldValueChanged(app, event)
            value = app.PointnEditField.Value;
            
            [a,b] = ismember(value,app.selected_point(:,1));
            
            if a
               node = app.selected_point(b,2);
               app.XEditField.Value = round(app.points3D(node,1),3);
               app.YEditField.Value = round(app.points3D(node,2),3);
               app.ZEditField.Value = round(app.points3D(node,3),3);
               
               % Press Find nearest node Button
               FindnearestnodeButtonPushed(app,event)
            end
            
            
        end

        % Button pushed function: FindnearestnodeButton
        function FindnearestnodeButtonPushed(app, event)
            x = app.XEditField.Value;
            y = app.YEditField.Value;
            z = app.ZEditField.Value;
            
            [distance,index] = findNode3D(x,y,z,app.points3D);
            
            app.actual_index = index;
            
            app.Label.Text = num2str(index);
            app.Label_2.Text = num2str(distance);
            
            xp = app.points3D(index,1);
            yp = app.points3D(index,2);
            zp = app.points3D(index,3);
            
            if isvalid(app.actual_plot)
                delete(app.actual_plot)
            end
            
            hold on
            app.actual_plot = scatter3(app.axis_plot,xp,yp,zp,'r','filled','o');
            hold off
            
        end

        % Button pushed function: AddselectedpointButton
        function AddselectedpointButtonPushed(app, event)
            index = app.actual_index;
            point = app.PointnEditField.Value;
            
            xp = app.points3D(index,1);
            yp = app.points3D(index,2);
            zp = app.points3D(index,3);
            
            [a,b] = ismember(point,app.selected_point(:,1));
            
            if a
                app.selected_point(b,2) = index;
                delete(app.plot_point(b));
                hold on;
                app.plot_point(b) = scatter3(app.axis_plot,xp,yp,zp,'k','filled','o');
                hold off;
            else
                app.selected_point = [app.selected_point;point index];
                hold on;
                app.plot_point = [app.plot_point, scatter3(app.axis_plot,xp,yp,zp,'k','filled','o')];
                hold off;
            end
            
            if isequal(app.selected_point(1,:),[0 0])
                app.selected_point(1,:) = [];
                app.plot_point(1) = [];
            end
            
        end

        % Button pushed function: ClearselectedpointButton
        function ClearselectedpointButtonPushed(app, event)
            point = app.PointnEditField.Value;
            
            [a,b] = ismember(point,app.selected_point(:,1));
            
            if a
                app.selected_point(b,:) = [];
                delete(app.plot_point(b));
                app.plot_point(b) = [];
            end
            
        end

        % Button pushed function: ConfirmButton_2
        function ConfirmButtonPushed(app, event)
            
            AddselectedpointButtonPushed(app, event)
            
            assignin('base','tracker3D_point',app.selected_point);
            
            close(app.fig);
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.CoordinatesPanel = uipanel(app.UIFigure);
            app.CoordinatesPanel.Title = 'Coordinates';
            app.XEditFieldLabel = uilabel(app.CoordinatesPanel);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.CoordinatesPanel, 'numeric');
            app.YEditFieldLabel = uilabel(app.CoordinatesPanel);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.CoordinatesPanel, 'numeric');
            app.ZEditFieldLabel = uilabel(app.CoordinatesPanel);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.CoordinatesPanel, 'numeric');
            app.FindnearestnodeButton = uibutton(app.CoordinatesPanel, 'push');
            app.FindnearestnodeButton.ButtonPushedFcn = createCallbackFcn(app, @FindnearestnodeButtonPushed, true);
            app.FindnearestnodeButton.Text = 'Find nearest node';
            app.PointnEditFieldLabel = uilabel(app.UIFigure);
            app.PointnEditFieldLabel.Text = 'Point n.';
            app.PointnEditField = uieditfield(app.UIFigure, 'numeric');
            app.PointnEditField.ValueChangedFcn = createCallbackFcn(app, @PointnEditFieldValueChanged, true);
            app.Panel_2 = uipanel(app.UIFigure);
            app.DiscetizationerrorLabel = uilabel(app.Panel_2);
            app.DiscetizationerrorLabel.Text = {'Discetization error:'; ''};
            app.Label_2 = uilabel(app.Panel_2);
            app.Label_2.Text = '';
            app.Label = uilabel(app.Panel_2);
            app.Label.Text = '';
            app.NodeindexLabel = uilabel(app.Panel_2);
            app.NodeindexLabel.Text = 'Node index: ';
            app.NodesmoothingSliderLabel = uilabel(app.UIFigure);
            app.NodesmoothingSliderLabel.Text = {'Node'; 'smoothing'};
            app.NodesmoothingSlider = uislider(app.UIFigure);
            app.NodesmoothingSlider.Limits = [1 10];
            app.NodesmoothingSlider.MajorTicks = [1 2 3 4 5 6 7 8 9 10];
            app.NodesmoothingSlider.ValueChangedFcn = createCallbackFcn(app, @NodesmoothingSliderValueChanged, true);
            app.NodesmoothingSlider.MinorTicks = [1 2 3 4 5 6 7 8 9 10];
            app.NodesmoothingSlider.Value = 1;
            app.AddselectedpointButton = uibutton(app.UIFigure, 'push');
            app.AddselectedpointButton.ButtonPushedFcn = createCallbackFcn(app, @AddselectedpointButtonPushed, true);
            app.AddselectedpointButton.Text = {'Add selected point'; ''};
            app.ConfirmButton_2 = uibutton(app.UIFigure, 'push');
            app.ConfirmButton_2.ButtonPushedFcn = createCallbackFcn(app, @ConfirmButtonPushed, true);
            app.ConfirmButton_2.Text = {'Confirm'; ''};
            app.ClearselectedpointButton = uibutton(app.UIFigure, 'push');
            app.ClearselectedpointButton.ButtonPushedFcn = createCallbackFcn(app, @ClearselectedpointButtonPushed, true);
            app.ClearselectedpointButton.Text = {'Clear selected point'; ''};
            app.SelectIDofthepointstotrackLabel = uilabel(app.UIFigure);
            app.SelectIDofthepointstotrackLabel.Text = {'(Select ID of the'; ' points to track)'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GetPointTracker3D(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end