classdef GetPointTrackerApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        CoordinatePanel          matlab.ui.container.Panel
        XEditFieldLabel          matlab.ui.control.Label
        XEditField               matlab.ui.control.NumericEditField
        YEditFieldLabel          matlab.ui.control.Label
        YEditField               matlab.ui.control.NumericEditField
        FindnearestnodeButton    matlab.ui.control.Button
        PointnEditFieldLabel     matlab.ui.control.Label
        PointnEditField          matlab.ui.control.NumericEditField
        AddcurrentpointButton    matlab.ui.control.Button
        ClearcurrentpointButton  matlab.ui.control.Button
        ConfirmButton            matlab.ui.control.Button
    end

    
    properties (Access = private)
        refimage % Reference image
        mask  % ROI mask
        info_image
        coefficent
        units
        fig
        selected_point
        image_plot
        axis_plot
        plot_point
        center_image
        pixel_spacing
        actual_plot
        actual_point
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, reference_image, mask, coeff_pixel, units, spacing)
            image = imread(reference_image);
            
            app.UIFigure.Color = [0.96 0.96 0.96];
            app.mask = mask;
            app.info_image = imfinfo(reference_image);
            app.coefficent = coeff_pixel;
            app.units = units; 
            % Valutare se siano necessari
            app.fig = figure;
            app.fig.OuterPosition(1) = app.UIFigure.OuterPosition(1)+app.UIFigure.OuterPosition(3);
            app.fig.OuterPosition(2) = app.UIFigure.OuterPosition(2);
            app.fig.OuterPosition(4) = app.UIFigure.OuterPosition(4)*1.5;
            app.fig.Color = 'white';
            app.axis_plot = axes(app.fig);
            app.selected_point = [0,0,0];
            app.plot_point = gobjects(1);
            app.PointnEditField.Value = 1;
            app.pixel_spacing = spacing;
            
            
            [M,N] = size(image);
            for i = 1:M
                for j = 1:N
                    if mask(i,j)
                    else
                        image(i,j) = 255;
                    end
                end
            end
            
            app.center_image(1) = round(N/2);    % X in nodi del centro
            app.center_image(2) = round(M/2);    % Y in nodi del centro

            image_width = app.info_image.Width;
            image_height = app.info_image.Height;
            
            x_center_px = round(image_width/2);
            y_center_px = round(image_height/2);
            
            x_axes = (1:image_width)';          x_axes = x_axes - x_center_px;
            y_axes = (1:image_height)';         y_axes = y_axes - y_center_px;
            
            
            app.image_plot = imshow(image,'Xdata',x_axes,'Ydata',y_axes,'Parent',app.axis_plot);
            app.axis_plot.Visible = 'On';
            hold on;
            app.actual_plot = scatter(app.axis_plot,0,0,'k');
            
            % Impedire allo script principale di andare avanti prima che la selezione dei punti sia
            % conclusa e la finestra di selezione si chiuda.
            uiwait(app.UIFigure)
        end

        % Value changed function: PointnEditField
        function PointnEditFieldValueChanged(app, event)
            value = app.PointnEditField.Value;
            
            [a,b] = ismember(value,app.selected_point(:,1));
            
            if a
               app.XEditField.Value = app.selected_point(b,2)*(app.pixel_spacing+1);
               app.YEditField.Value = app.selected_point(b,3)*(app.pixel_spacing+1);
               % Press Find nearest node Button
               FindnearestnodeButtonPushed(app,event)
            end
        end

        % Button pushed function: FindnearestnodeButton
        function FindnearestnodeButtonPushed(app, event)
            point_pixel_distance = [0,0];
            point_pixel_distance(1) = app.XEditField.Value;
            point_pixel_distance(2) = app.YEditField.Value;
            
            points_nodes_distance = round(point_pixel_distance / (app.pixel_spacing + 1));
            node_position = points_nodes_distance * (app.pixel_spacing+1);
            
            app.actual_point = points_nodes_distance;
            
            if isvalid(app.actual_plot)
                delete(app.actual_plot)
            end
            
            app.actual_plot = scatter(app.axis_plot,node_position(1),node_position(2),'r','filled','o');
            
        end

        % Button pushed function: AddcurrentpointButton
        function AddcurrentpointButtonPushed(app, event)
            pointID = app.PointnEditField.Value;
            node_position = app.actual_point * (app.pixel_spacing+1);
            
            [a,b] = ismember(pointID,app.selected_point(:,1));
            
            if a
                app.selected_point(b,1) = pointID;
                app.selected_point(b,2:3) = app.actual_point;
                delete(app.plot_point(b));
                
                app.plot_point(b) = scatter(app.axis_plot,node_position(1),node_position(2),'g','filled','o');
                
            else
                app.selected_point = [app.selected_point; pointID app.actual_point];
                
                app.plot_point = [app.plot_point, scatter(app.axis_plot,node_position(1),node_position(2),'g','filled','o');];
                
            end
            
            if isequal(app.selected_point(1,:),[0 0 0])
                app.selected_point(1,:) = [];
                app.plot_point(1) = [];
            end
        end

        % Button pushed function: ClearcurrentpointButton
        function ClearcurrentpointButtonPushed(app, event)
            pointID = app.PointnEditField.Value;
            
            [a,b] = ismember(pointID,app.selected_point(:,1));
            
            if a
                app.selected_point(b,:) = [];
                delete(app.plot_point(b));
                app.plot_point(b) = [];
            end
            
        end

        % Button pushed function: ConfirmButton
        function ConfirmButtonPushed(app, event)
            AddcurrentpointButtonPushed(app, event)
            
            assignin('base','tracker_point',app.selected_point);
            
            close(app.fig);
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.CoordinatePanel = uipanel(app.UIFigure);
            app.CoordinatePanel.Title = 'Coordinate';
            app.XEditFieldLabel = uilabel(app.CoordinatePanel);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.CoordinatePanel, 'numeric');
            app.YEditFieldLabel = uilabel(app.CoordinatePanel);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.CoordinatePanel, 'numeric');
            app.FindnearestnodeButton = uibutton(app.CoordinatePanel, 'push');
            app.FindnearestnodeButton.ButtonPushedFcn = createCallbackFcn(app, @FindnearestnodeButtonPushed, true);
            app.FindnearestnodeButton.Text = 'Find nearest node';
            app.PointnEditFieldLabel = uilabel(app.UIFigure);
            app.PointnEditFieldLabel.Text = 'Point n.';
            app.PointnEditField = uieditfield(app.UIFigure, 'numeric');
            app.PointnEditField.ValueChangedFcn = createCallbackFcn(app, @PointnEditFieldValueChanged, true);
            app.AddcurrentpointButton = uibutton(app.UIFigure, 'push');
            app.AddcurrentpointButton.ButtonPushedFcn = createCallbackFcn(app, @AddcurrentpointButtonPushed, true);
            app.AddcurrentpointButton.Text = 'Add current point';
            app.ClearcurrentpointButton = uibutton(app.UIFigure, 'push');
            app.ClearcurrentpointButton.ButtonPushedFcn = createCallbackFcn(app, @ClearcurrentpointButtonPushed, true);
            app.ClearcurrentpointButton.Text = 'Clear current point';
            app.ConfirmButton = uibutton(app.UIFigure, 'push');
            app.ConfirmButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmButtonPushed, true);
            app.ConfirmButton.Text = 'Confirm';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GetPointTrackerApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end