classdef GetSectionPoint3D < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        NodesmoothingSliderLabel     matlab.ui.control.Label
        NodesmoothingSlider          matlab.ui.control.Slider
        SectioncoordinatesPanel      matlab.ui.container.Panel
        ZsectionEditFieldLabel       matlab.ui.control.Label
        ZsectionEditField            matlab.ui.control.NumericEditField
        ToleranceLabel               matlab.ui.control.Label
        ToleranceEditField           matlab.ui.control.NumericEditField
        PlotsectionButton            matlab.ui.control.Button
        ButtonGroup                  matlab.ui.container.ButtonGroup
        Section1upperButton          matlab.ui.control.RadioButton
        Section2lowerButton          matlab.ui.control.RadioButton
        ConfirmcurrentsectionButton  matlab.ui.control.Button
        FinishButton                 matlab.ui.control.Button
        NofpointsLabel               matlab.ui.control.Label
        Label                        matlab.ui.control.Label
        FrameSliderLabel             matlab.ui.control.Label
        FrameSlider                  matlab.ui.control.Slider
    end

    
    properties (Access = private)
        points3D
        plot_cloud
        fig
        axis_plot
        actual_plot
        section_coordinates
        section1
        section1coordinates
        section1tolerance
        section1plot
        section2
        section2coordinates
        section2tolerance
        section2plot
        actual_nodes
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, points3D)
            app.points3D = points3D;
            app.UIFigure.Color = [0.94 0.94 0.94];
            app.fig = figure;
            app.fig.Position(1) = app.UIFigure.OuterPosition(1)+app.UIFigure.OuterPosition(3);
            app.fig.Position(2) = app.UIFigure.OuterPosition(2);
            app.fig.Position(4) = app.UIFigure.OuterPosition(4);
            app.fig.Color = 'white';
            app.axis_plot = axes(app.fig);
            app.NodesmoothingSlider.Value = 10;
            
            nframes = size(app.points3D,3);
            app.FrameSlider.Limits = [1,nframes];
            
            % Inizializzo i plot
            app.actual_plot = scatter3(app.axis_plot,points3D(1,1),points3D(1,2),points3D(3,1),'Visible','off');
            app.section1plot = scatter3(app.axis_plot,points3D(1,1),points3D(1,2),points3D(3,1),'Visible','off');
            app.section2plot = scatter3(app.axis_plot,points3D(1,1),points3D(1,2),points3D(3,1),'Visible','off');
            
            % Plotto i punti filtrati all'inizio della function
            app.plot_cloud = plotTracker3D(app.axis_plot,app.points3D,10);
            
            % Impedire allo script principale di andare avanti prima che la selezione dei punti sia
            % conclusa e la finestra di selezione si chiuda.
            uiwait(app.UIFigure)

        end

        % Value changed function: FrameSlider, NodesmoothingSlider
        function NodesmoothingSliderValueChanged(app, event)
            value = app.NodesmoothingSlider.Value;
            value = round(value);
            
            frame = app.FrameSlider.Value;
            frame = round(frame);
            
            delete(app.plot_cloud);
            hold on;
            app.plot_cloud = plotTracker3D(app.axis_plot,app.points3D(:,:,frame),value);
            hold off;
            
            if isvalid(app.section1plot)
                delete(app.section1plot)
                xp = app.section1coordinates(:,1,frame);
                yp = app.section1coordinates(:,2,frame);
                zp = app.section1coordinates(:,3,frame);
                hold on
                app.section1plot = scatter3(app.axis_plot,xp,yp,zp,'k','filled','o');
                hold off
            end
            
            if isvalid(app.section2plot)
                delete(app.section2plot)
                xp = app.section2coordinates(:,1,frame);
                yp = app.section2coordinates(:,2,frame);
                zp = app.section2coordinates(:,3,frame);
                hold on
                app.section2plot = scatter3(app.axis_plot,xp,yp,zp,'k','filled','o');
                hold off
            end
            
        end

        % Button pushed function: PlotsectionButton
        function PlotsectionButtonPushed(app, event)
            z = app.ZsectionEditField.Value;
            tol = app.ToleranceEditField.Value;
            
            frame = app.FrameSlider.Value;
            frame = round(frame);
            
            app.actual_nodes = findSection3D(app.points3D(:,:,frame),z,tol);
            app.section_coordinates = extractSectionNode(app.actual_nodes,app.points3D);
            
            N = size(app.actual_nodes,1);
            app.Label.Text = num2str(N);
            
            xp = app.section_coordinates(:,1,frame);
            yp = app.section_coordinates(:,2,frame);
            zp = app.section_coordinates(:,3,frame);
            
            if isvalid(app.actual_plot)
                delete(app.actual_plot)
            end
            
            hold on
            app.actual_plot = scatter3(app.axis_plot,xp,yp,zp,'r','filled','o');
            hold off
        end

        % Button pushed function: ConfirmcurrentsectionButton
        function ConfirmcurrentsectionButtonPushed(app, event)
            frame = app.FrameSlider.Value;
            frame = round(frame);
            
            xp = app.section_coordinates(:,1,frame);
            yp = app.section_coordinates(:,2,frame);
            zp = app.section_coordinates(:,3,frame);
            
            if app.Section1upperButton.Value
                app.section1 = app.actual_nodes;
                app.section1coordinates = app.section_coordinates;
                app.section1tolerance = app.ToleranceEditField.Value;
                if isvalid(app.section1plot)
                    delete(app.section1plot)
                end
                hold on
                app.section1plot = scatter3(app.axis_plot,xp,yp,zp,'k','filled','o');
                hold off
                delete(app.actual_plot)
            elseif app.Section2lowerButton.Value
                app.section2 = app.actual_nodes;
                app.section2coordinates = app.section_coordinates;
                app.section2tolerance = app.ToleranceEditField.Value;
                if isvalid(app.section2plot)
                    delete(app.section2plot)
                end
                hold on
                app.section2plot = scatter3(app.axis_plot,xp,yp,zp,'k','filled','o');
                hold off
                delete(app.actual_plot)
            end
            
        end

        % Button pushed function: FinishButton
        function FinishButtonPushed(app, event)
            ConfirmcurrentsectionButtonPushed(app, event)
            
            
            assignin('base','section1_index',app.section1);
            assignin('base','section2_index',app.section2);
            assignin('base','tolerance1',app.section1tolerance);
            assignin('base','tolerance2',app.section2tolerance);
            
            close(app.fig);
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.NodesmoothingSliderLabel = uilabel(app.UIFigure);
            app.NodesmoothingSliderLabel.Text = {'Node'; 'smoothing'};
            app.NodesmoothingSlider = uislider(app.UIFigure);
            app.NodesmoothingSlider.Limits = [1 10];
            app.NodesmoothingSlider.MajorTicks = [1 2 3 4 5 6 7 8 9 10];
            app.NodesmoothingSlider.ValueChangedFcn = createCallbackFcn(app, @NodesmoothingSliderValueChanged, true);
            app.NodesmoothingSlider.MinorTicks = [1 2 3 4 5 6 7 8 9 10];
            app.NodesmoothingSlider.Value = 1;
            app.SectioncoordinatesPanel = uipanel(app.UIFigure);
            app.SectioncoordinatesPanel.Title = 'Section coordinates';
            app.ZsectionEditFieldLabel = uilabel(app.SectioncoordinatesPanel);
            app.ZsectionEditFieldLabel.Text = {'Z section'; ''};
            app.ZsectionEditField = uieditfield(app.SectioncoordinatesPanel, 'numeric');
            app.ToleranceLabel = uilabel(app.SectioncoordinatesPanel);
            app.ToleranceLabel.Text = 'Tolerance';
            app.ToleranceEditField = uieditfield(app.SectioncoordinatesPanel, 'numeric');
            app.PlotsectionButton = uibutton(app.SectioncoordinatesPanel, 'push');
            app.PlotsectionButton.ButtonPushedFcn = createCallbackFcn(app, @PlotsectionButtonPushed, true);
            app.PlotsectionButton.Text = 'Plot section';
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.Section1upperButton = uiradiobutton(app.ButtonGroup);
            app.Section1upperButton.Text = 'Section 1 (upper)';
            app.Section1upperButton.Value = true;
            app.Section2lowerButton = uiradiobutton(app.ButtonGroup);
            app.Section2lowerButton.Text = 'Section 2 (lower)';
            app.ConfirmcurrentsectionButton = uibutton(app.UIFigure, 'push');
            app.ConfirmcurrentsectionButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmcurrentsectionButtonPushed, true);
            app.ConfirmcurrentsectionButton.Text = 'Confirm current section';
            app.FinishButton = uibutton(app.UIFigure, 'push');
            app.FinishButton.ButtonPushedFcn = createCallbackFcn(app, @FinishButtonPushed, true);
            app.FinishButton.Text = 'Finish';
            app.NofpointsLabel = uilabel(app.UIFigure);
            app.NofpointsLabel.Text = 'N. of points';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.FrameSliderLabel = uilabel(app.UIFigure);
            app.FrameSliderLabel.Text = 'Frame';
            app.FrameSlider = uislider(app.UIFigure);
            app.FrameSlider.ValueChangedFcn = createCallbackFcn(app, @NodesmoothingSliderValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GetSectionPoint3D(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end